classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        imageprocessingUIFigure  matlab.ui.Figure
        FileMenu                 matlab.ui.container.Menu
        LoadImageMenu            matlab.ui.container.Menu
        SaveImageMenu            matlab.ui.container.Menu
        EditMenu                 matlab.ui.container.Menu
        ConverttoMenu            matlab.ui.container.Menu
        BlackandwhiteMenu        matlab.ui.container.Menu
        BinaryMenu               matlab.ui.container.Menu
        TransformMenu            matlab.ui.container.Menu
        ChangeresolutionMenu     matlab.ui.container.Menu
        Rotate180Menu            matlab.ui.container.Menu
        Rotate90Menu             matlab.ui.container.Menu
        FlipHorizontalMenu       matlab.ui.container.Menu
        FlipvertcalMenu          matlab.ui.container.Menu
        HistogramMenu            matlab.ui.container.Menu
        StepbackMenu             matlab.ui.container.Menu
        ResetMenu                matlab.ui.container.Menu
        AddnoiseMenu             matlab.ui.container.Menu
        PoissonMenu              matlab.ui.container.Menu
        GaussianMenu             matlab.ui.container.Menu
        SaltandpepperMenu        matlab.ui.container.Menu
        AddfilterMenu            matlab.ui.container.Menu
        MoyenneurMenu            matlab.ui.container.Menu
        GaussianMenu_2           matlab.ui.container.Menu
        MedianMenu               matlab.ui.container.Menu
        modifiedImageAxes        matlab.ui.control.UIAxes
        RedDropDownLabel         matlab.ui.control.Label
        RedDropDown              matlab.ui.control.DropDown
        inputImageAxes           matlab.ui.control.UIAxes
        StepbackButton           matlab.ui.control.Button
        ResetButton              matlab.ui.control.Button
        ResizeButton             matlab.ui.control.Button
        HistogramButton          matlab.ui.control.Button
        GreenDropDownLabel       matlab.ui.control.Label
        GreenDropDown            matlab.ui.control.DropDown
        BlueDropDownLabel        matlab.ui.control.Label
        BlueDropDown             matlab.ui.control.DropDown
        ImagemodifiedLabel       matlab.ui.control.Label
        OriginalimageLabel       matlab.ui.control.Label
        ContrastSliderLabel      matlab.ui.control.Label
        ContrastSlider           matlab.ui.control.Slider
        BrightnessSliderLabel    matlab.ui.control.Label
        BrightnessSlider         matlab.ui.control.Slider
        PoweredbyMarkIssaLabel   matlab.ui.control.Label
    end


    properties (Access = public)
       
        inputImage; 
        
        % Load
        imageList;
        fullPath;
        fullfile;
        Pathname;
        Filename;
        original;  
        hsv_space;
        % convert
         inputImage_bw;
        inputImage_binarny;
        inputImage_index;
         % Transform
        inputImage_rotate;
        inputImage_otocenie_180;
        inputImage_otocenie_90;
        inputImage_flip_horizontal;
        inputImage_flip_vertical;
        %noise
        inputImage_poisson;
        inputImage_gaussian;
        inputImage_salt;
         %Filtre
        inputImage_gaussian_filter;
        inputImage_median_filter;
        inputImage_moyenneur_filter;
         %Histogram
        inputImage_Red;
        inputImage_Green;
        inputImage_Blue;
        inputImage_pixelCountRed;
        inputImage_pixelCountGreen;
        inputImage_pixelCountBlue;
        inputImage_Red_Hist;
        inputImage_Green_Hist;
        inputImage_Blue_Hist;
         % rgb
        valueRed2;
        inputImage2;
        lower;
        upper;
        lower2
        upper2;
        rcon;
        originalImage;     
        backgroundImage;
        h;
        d;
        val;
        rgb;
        manualDetectSlider;
         % rgb Segmetation
        rgb_image;
        inputImage_jas;
        RGB2;
        inputImage_crop_image;
        inputImage_bitDepth;
        img2;
        sliderValue;
        inputImage_red_value;
        inputImage_background;
         %contrast
        inputImage_contrast;
        inputImage_histeq;


    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)


        end

        % Menu selected function: LoadImageMenu
        function LoadImageMenuSelected(app, event)
                loadImage;
        end

        % Menu selected function: SaveImageMenu
        function SaveImageMenuSelected(app, event)
            saveImage;
        end

        % Callback function
        function ConverttoMenuSelected(app, event)
            
        end

        % Menu selected function: BlackandwhiteMenu
        function BlackandwhiteMenuSelected(app, event)
            blackAndWhiteModule;
        end

        % Menu selected function: BinaryMenu
        function BinaryMenuSelected(app, event)
            binaryModule;
        end

        % Callback function: ChangeresolutionMenu, ResizeButton
        function ChangeresolutionMenuSelected(app, event)
              changeResolutionModule;
        end

        % Menu selected function: Rotate180Menu
        function Rotate180MenuSelected(app, event)
             rotate180Module;
        end

        % Menu selected function: FlipHorizontalMenu
        function FlipHorizontalMenuSelected(app, event)
             flipHorizontalModule;
        end

        % Menu selected function: FlipvertcalMenu
        function FlipvertcalMenuSelected(app, event)
            flipVerticalModule;
        end

        % Menu selected function: MoyenneurMenu
        function MoyenneurMenuSelected(app, event)
            moyenneurFilterModule;
        end

        % Menu selected function: GaussianMenu_2
        function GaussianMenu_2Selected(app, event)
            gaussianFilterModule;
        end

        % Menu selected function: MedianMenu
        function MedianMenuSelected(app, event)
            medianFilterModule;
        end

        % Menu selected function: PoissonMenu
        function PoissonMenuSelected(app, event)
            poissonNoiseModule;
        end

        % Menu selected function: GaussianMenu
        function GaussianMenuSelected(app, event)
            gaussianNoiseModule;
        end

        % Menu selected function: SaltandpepperMenu
        function SaltandpepperMenuSelected(app, event)
             saltNoiseModule;
        end

        % Menu selected function: Rotate90Menu
        function Rotate90MenuSelected(app, event)
            rotate90Module;
        end

        % Callback function
        function StepbackMenuSelected(app, event)

        end

        % Callback function: StepbackButton, StepbackMenu
        function StepbackButtonPushed(app, event)
             if numel(app.imageList) > 1 
              app.imageList(end)=[];
              app.inputImage = app.imageList{end};
              %app.imageList
              imshow(app.inputImage, 'Parent', app.modifiedImageAxes);                     
              end
        end

        % Callback function: ResetButton, ResetMenu
        function ResetButtonPushed(app, event)
              app.inputImage = app.imageList{1};
           
           imshow(app.inputImage, 'Parent', app.modifiedImageAxes);
        end

        % Callback function: HistogramButton, HistogramMenu
        function HistogramMenuSelected(app, event)
            histogramModule;
        end

        % Value changed function: RedDropDown
        function RedDropDownValueChanged(app, event)
            redProfileSegmentationModule;
        end

        % Value changed function: GreenDropDown
        function GreenDropDownValueChanged(app, event)
             greenProfileSegmentationModule;
            
        end

        % Value changed function: BlueDropDown
        function BlueDropDownValueChanged(app, event)
            blueProfileSegmentationModule;
            
        end

        % Value changed function: ContrastSlider
        function ContrastSliderValueChanged(app, event)
             value_contrast = app.ContrastSlider.Value;
            app.inputImage_contrast=app.inputImage.*value_contrast;
            imshow(app.inputImage_contrast, 'Parent', app.modifiedImageAxes);
            
        end

        % Value changed function: BrightnessSlider
        function BrightnessSliderValueChanged(app, event)
            brightlessModule;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.imageprocessingUIFigure = uifigure;
            app.imageprocessingUIFigure.Name = 'image processing';
            app.FileMenu = uimenu(app.imageprocessingUIFigure);
            app.FileMenu.Text = 'File';
            app.LoadImageMenu = uimenu(app.FileMenu);
            app.LoadImageMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadImageMenuSelected, true);
            app.LoadImageMenu.Accelerator = 'o';
            app.LoadImageMenu.Text = 'Load Image';
            app.SaveImageMenu = uimenu(app.FileMenu);
            app.SaveImageMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveImageMenuSelected, true);
            app.SaveImageMenu.Separator = 'on';
            app.SaveImageMenu.Accelerator = 's';
            app.SaveImageMenu.Text = 'Save Image';
            app.EditMenu = uimenu(app.imageprocessingUIFigure);
            app.EditMenu.Text = 'Edit';
            app.ConverttoMenu = uimenu(app.EditMenu);
            app.ConverttoMenu.Text = 'Convert to ';
            app.BlackandwhiteMenu = uimenu(app.ConverttoMenu);
            app.BlackandwhiteMenu.MenuSelectedFcn = createCallbackFcn(app, @BlackandwhiteMenuSelected, true);
            app.BlackandwhiteMenu.Text = 'Black and white';
            app.BinaryMenu = uimenu(app.ConverttoMenu);
            app.BinaryMenu.MenuSelectedFcn = createCallbackFcn(app, @BinaryMenuSelected, true);
            app.BinaryMenu.Text = 'Binary';
            app.TransformMenu = uimenu(app.EditMenu);
            app.TransformMenu.Text = 'Transform ';
            app.ChangeresolutionMenu = uimenu(app.TransformMenu);
            app.ChangeresolutionMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeresolutionMenuSelected, true);
            app.ChangeresolutionMenu.Text = 'Change resolution';
            app.Rotate180Menu = uimenu(app.TransformMenu);
            app.Rotate180Menu.MenuSelectedFcn = createCallbackFcn(app, @Rotate180MenuSelected, true);
            app.Rotate180Menu.Text = 'Rotate 180';
            app.Rotate90Menu = uimenu(app.TransformMenu);
            app.Rotate90Menu.MenuSelectedFcn = createCallbackFcn(app, @Rotate90MenuSelected, true);
            app.Rotate90Menu.Text = 'Rotate 90';
            app.FlipHorizontalMenu = uimenu(app.TransformMenu);
            app.FlipHorizontalMenu.MenuSelectedFcn = createCallbackFcn(app, @FlipHorizontalMenuSelected, true);
            app.FlipHorizontalMenu.Text = 'Flip Horizontal';
            app.FlipvertcalMenu = uimenu(app.TransformMenu);
            app.FlipvertcalMenu.MenuSelectedFcn = createCallbackFcn(app, @FlipvertcalMenuSelected, true);
            app.FlipvertcalMenu.Text = 'Flip vertcal';
            app.HistogramMenu = uimenu(app.EditMenu);
            app.HistogramMenu.MenuSelectedFcn = createCallbackFcn(app, @HistogramMenuSelected, true);
            app.HistogramMenu.Text = 'Histogram';
            app.StepbackMenu = uimenu(app.EditMenu);
            app.StepbackMenu.MenuSelectedFcn = createCallbackFcn(app, @StepbackButtonPushed, true);
            app.StepbackMenu.Accelerator = 'z';
            app.StepbackMenu.Text = 'Step back';
            app.ResetMenu = uimenu(app.EditMenu);
            app.ResetMenu.MenuSelectedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetMenu.Accelerator = 'r';
            app.ResetMenu.Text = 'Reset';
            app.AddnoiseMenu = uimenu(app.imageprocessingUIFigure);
            app.AddnoiseMenu.Text = 'Add noise';
            app.PoissonMenu = uimenu(app.AddnoiseMenu);
            app.PoissonMenu.MenuSelectedFcn = createCallbackFcn(app, @PoissonMenuSelected, true);
            app.PoissonMenu.Text = 'Poisson';
            app.GaussianMenu = uimenu(app.AddnoiseMenu);
            app.GaussianMenu.MenuSelectedFcn = createCallbackFcn(app, @GaussianMenuSelected, true);
            app.GaussianMenu.Text = 'Gaussian';
            app.SaltandpepperMenu = uimenu(app.AddnoiseMenu);
            app.SaltandpepperMenu.MenuSelectedFcn = createCallbackFcn(app, @SaltandpepperMenuSelected, true);
            app.SaltandpepperMenu.Text = 'Salt and pepper';
            app.AddfilterMenu = uimenu(app.imageprocessingUIFigure);
            app.AddfilterMenu.Text = 'Add filter';
            app.MoyenneurMenu = uimenu(app.AddfilterMenu);
            app.MoyenneurMenu.MenuSelectedFcn = createCallbackFcn(app, @MoyenneurMenuSelected, true);
            app.MoyenneurMenu.Text = 'Moyenneur';
            app.GaussianMenu_2 = uimenu(app.AddfilterMenu);
            app.GaussianMenu_2.MenuSelectedFcn = createCallbackFcn(app, @GaussianMenu_2Selected, true);
            app.GaussianMenu_2.Text = 'Gaussian';
            app.MedianMenu = uimenu(app.AddfilterMenu);
            app.MedianMenu.MenuSelectedFcn = createCallbackFcn(app, @MedianMenuSelected, true);
            app.MedianMenu.Text = 'Median';
            app.modifiedImageAxes = uiaxes(app.imageprocessingUIFigure);
            app.modifiedImageAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.modifiedImageAxes.XTickLabel = {'0'; '0.2'; '0.4'; '0.6'; '0.8'; '1'};
            app.RedDropDownLabel = uilabel(app.imageprocessingUIFigure);
            app.RedDropDownLabel.Text = 'Red';
            app.RedDropDown = uidropdown(app.imageprocessingUIFigure);
            app.RedDropDown.Items = {'Red profil', 'Soft', 'Middle soft', 'Middle', 'Hard', 'Very hard'};
            app.RedDropDown.Editable = 'on';
            app.RedDropDown.ValueChangedFcn = createCallbackFcn(app, @RedDropDownValueChanged, true);
            app.RedDropDown.Value = 'Red profil';
            app.inputImageAxes = uiaxes(app.imageprocessingUIFigure);
            app.StepbackButton = uibutton(app.imageprocessingUIFigure, 'push');
            app.StepbackButton.ButtonPushedFcn = createCallbackFcn(app, @StepbackButtonPushed, true);
            app.StepbackButton.Icon = 'back.png';
            app.StepbackButton.Text = 'Step back';
            app.ResetButton = uibutton(app.imageprocessingUIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Icon = 'reset.png';
            app.ResetButton.Text = 'Reset';
            app.ResizeButton = uibutton(app.imageprocessingUIFigure, 'push');
            app.ResizeButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeresolutionMenuSelected, true);
            app.ResizeButton.Icon = 'images.jpg';
            app.ResizeButton.Text = 'Resize';
            app.HistogramButton = uibutton(app.imageprocessingUIFigure, 'push');
            app.HistogramButton.ButtonPushedFcn = createCallbackFcn(app, @HistogramMenuSelected, true);
            app.HistogramButton.Text = 'Histogram';
            app.GreenDropDownLabel = uilabel(app.imageprocessingUIFigure);
            app.GreenDropDownLabel.Text = 'Green';
            app.GreenDropDown = uidropdown(app.imageprocessingUIFigure);
            app.GreenDropDown.Items = {'Green profile', 'Soft', 'Middle soft', 'Middle', 'Hard', 'Very hard'};
            app.GreenDropDown.Editable = 'on';
            app.GreenDropDown.ValueChangedFcn = createCallbackFcn(app, @GreenDropDownValueChanged, true);
            app.GreenDropDown.Value = 'Green profile';
            app.BlueDropDownLabel = uilabel(app.imageprocessingUIFigure);
            app.BlueDropDownLabel.Text = 'Blue';
            app.BlueDropDown = uidropdown(app.imageprocessingUIFigure);
            app.BlueDropDown.Items = {'Blue profile', 'Soft', 'Middle soft', 'Middle', 'Hard', 'Very hard'};
            app.BlueDropDown.Editable = 'on';
            app.BlueDropDown.ValueChangedFcn = createCallbackFcn(app, @BlueDropDownValueChanged, true);
            app.BlueDropDown.Value = 'Blue profile';
            app.ImagemodifiedLabel = uilabel(app.imageprocessingUIFigure);
            app.ImagemodifiedLabel.Text = 'Image modified';
            app.OriginalimageLabel = uilabel(app.imageprocessingUIFigure);
            app.OriginalimageLabel.Text = 'Original image';
            app.ContrastSliderLabel = uilabel(app.imageprocessingUIFigure);
            app.ContrastSliderLabel.Text = 'Contrast';
            app.ContrastSlider = uislider(app.imageprocessingUIFigure);
            app.ContrastSlider.Limits = [1 255];
            app.ContrastSlider.ValueChangedFcn = createCallbackFcn(app, @ContrastSliderValueChanged, true);
            app.ContrastSlider.Value = 1;
            app.BrightnessSliderLabel = uilabel(app.imageprocessingUIFigure);
            app.BrightnessSliderLabel.Text = ' Brightness';
            app.BrightnessSlider = uislider(app.imageprocessingUIFigure);
            app.BrightnessSlider.Limits = [-100 100];
            app.BrightnessSlider.ValueChangedFcn = createCallbackFcn(app, @BrightnessSliderValueChanged, true);
            app.PoweredbyMarkIssaLabel = uilabel(app.imageprocessingUIFigure);
            app.PoweredbyMarkIssaLabel.Text = 'Powered by Mark & Issa';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.imageprocessingUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.imageprocessingUIFigure)
        end
    end
end